classdef User_Input < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        EditField_3   matlab.ui.control.EditField
        EnterfrequencyofacvoltagesourceinHzEditField_2  matlab.ui.control.NumericEditField
        EnterfrequencyofacvoltagesourceinHzEditField_2Label  matlab.ui.control.Label
        EnteramplitudeofacvoltagesourceEditField_2  matlab.ui.control.NumericEditField
        EnteramplitudeofacvoltagesourceEditField_2Label  matlab.ui.control.Label
        EntervalueofinductanceinhenryEditField_2  matlab.ui.control.NumericEditField
        EntervalueofinductanceinhenryEditField_2Label  matlab.ui.control.Label
        EntervalueofcapacitanceinfaradEditField_2  matlab.ui.control.NumericEditField
        EntervalueofcapacitanceinfaradEditField_2Label  matlab.ui.control.Label
        EntervalueofresistanceinohmsEditField_2  matlab.ui.control.NumericEditField
        EntervalueofresistanceinohmsEditField_2Label  matlab.ui.control.Label
        EditField_2   matlab.ui.control.EditField
        EnterfrequencyofacvoltagesourceinHzEditField  matlab.ui.control.NumericEditField
        EnterfrequencyofacvoltagesourceinHzEditFieldLabel  matlab.ui.control.Label
        EditField     matlab.ui.control.EditField
        SubmitButton  matlab.ui.control.Button
        EnteramplitudeofacvoltagesourceEditField  matlab.ui.control.NumericEditField
        EnteramplitudeofacvoltagesourceEditFieldLabel  matlab.ui.control.Label
        EntervalueofinductanceinhenryEditField  matlab.ui.control.NumericEditField
        EntervalueofinductanceinhenryEditFieldLabel  matlab.ui.control.Label
        EntervalueofcapacitanceinfaradEditField  matlab.ui.control.NumericEditField
        EntervalueofcapacitanceinfaradEditFieldLabel  matlab.ui.control.Label
        EntervalueofresistanceinohmsEditField  matlab.ui.control.NumericEditField
        EntervalueofresistanceinohmsEditFieldLabel  matlab.ui.control.Label
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            assignin("base",'r1',app.EntervalueofresistanceinohmsEditField.Value)
            assignin("base",'c1',app.EntervalueofcapacitanceinfaradEditField.Value)
            assignin("base",'l1',app.EntervalueofinductanceinhenryEditField.Value) 
            assignin("base",'v1',app.EnteramplitudeofacvoltagesourceEditField.Value)
            assignin("base",'f1',app.EnterfrequencyofacvoltagesourceinHzEditField.Value)
            assignin("base",'r2',app.EntervalueofresistanceinohmsEditField_2.Value)
            assignin("base",'c2',app.EntervalueofcapacitanceinfaradEditField_2.Value) 
            assignin("base",'l2',app.EntervalueofinductanceinhenryEditField_2.Value)
            assignin("base",'v2',app.EnteramplitudeofacvoltagesourceEditField_2.Value)
            assignin("base",'f2',app.EnterfrequencyofacvoltagesourceinHzEditField_2.Value)
            load_system("circuit_simulation.slx")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EntervalueofresistanceinohmsEditFieldLabel = uilabel(app.UIFigure);
            app.EntervalueofresistanceinohmsEditFieldLabel.Text = 'Enter value of resistance(in ohms)';
            app.EntervalueofresistanceinohmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.EntervalueofcapacitanceinfaradEditFieldLabel = uilabel(app.UIFigure);
            app.EntervalueofcapacitanceinfaradEditFieldLabel.Text = 'Enter value of capacitance(in farad)';
            app.EntervalueofcapacitanceinfaradEditField = uieditfield(app.UIFigure, 'numeric');
            app.EntervalueofinductanceinhenryEditFieldLabel = uilabel(app.UIFigure);
            app.EntervalueofinductanceinhenryEditFieldLabel.Text = 'Enter value of inductance(in henry)';
            app.EntervalueofinductanceinhenryEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnteramplitudeofacvoltagesourceEditFieldLabel = uilabel(app.UIFigure);
            app.EnteramplitudeofacvoltagesourceEditFieldLabel.Text = 'Enter amplitude of ac voltage source';
            app.EnteramplitudeofacvoltagesourceEditField = uieditfield(app.UIFigure, 'numeric');
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'Series RLC Circuit Parameters';
            app.EnterfrequencyofacvoltagesourceinHzEditFieldLabel = uilabel(app.UIFigure);
            app.EnterfrequencyofacvoltagesourceinHzEditFieldLabel.Text = 'Enter frequency of ac voltage source(in Hz)';
            app.EnterfrequencyofacvoltagesourceinHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Value = 'Parallel RLC Circuit Parameters';
            app.EntervalueofresistanceinohmsEditField_2Label = uilabel(app.UIFigure);
            app.EntervalueofresistanceinohmsEditField_2Label.Text = 'Enter value of resistance(in ohms)';
            app.EntervalueofresistanceinohmsEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EntervalueofcapacitanceinfaradEditField_2Label = uilabel(app.UIFigure);
            app.EntervalueofcapacitanceinfaradEditField_2Label.Text = 'Enter value of capacitance(in farad)';
            app.EntervalueofcapacitanceinfaradEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EntervalueofinductanceinhenryEditField_2Label = uilabel(app.UIFigure);
            app.EntervalueofinductanceinhenryEditField_2Label.Text = 'Enter value of inductance(in henry)';
            app.EntervalueofinductanceinhenryEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EnteramplitudeofacvoltagesourceEditField_2Label = uilabel(app.UIFigure);
            app.EnteramplitudeofacvoltagesourceEditField_2Label.Text = 'Enter amplitude of ac voltage source';
            app.EnteramplitudeofacvoltagesourceEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EnterfrequencyofacvoltagesourceinHzEditField_2Label = uilabel(app.UIFigure);
            app.EnterfrequencyofacvoltagesourceinHzEditField_2Label.Text = 'Enter frequency of ac voltage source(in Hz)';
            app.EnterfrequencyofacvoltagesourceinHzEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.Value = '(Press Run button first)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = User_Input

            % Associate the Simulink Model
            app.Simulation = simulation('circuit_simulation');

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end